classdef Minesweeper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WelcometominesweeperLabel      matlab.ui.control.Label
        panel1                         matlab.ui.container.Panel
        Minefield                      matlab.ui.container.GridLayout
        StartButton                    matlab.ui.control.Button
        ChoosedifficultyDropDown       matlab.ui.control.DropDown
        ChoosedifficultyDropDownLabel  matlab.ui.control.Label
    end

   properties (Access = private)
        mineCounterValue string;
        flagCounterValue string;
   end


    methods (Access = public)


        function generateBomb(app, minefield, difficulty)
    % Calculate number of buttons based on field size
    windowSize=app.UIFigure.Position;
    nButtons = numel(minefield);
    
    app.mineCounterValue = '';
    app.flagCounterValue = '';
    % Initialize button matrix
    buttons = cell(size(minefield));
    app.panel1.Visible='on';
    uilabel(app.UIFigure, 'Position',[170,510,400,60],...
'FontSize', 18,'Text','Bombs remaining:')
uilabel(app.UIFigure, 'Position',[670,510,400,60],...
'FontSize', 18,'Text','Flags remaining:')
    mineCounter=uilabel(app.UIFigure, 'Position',[320,510,60,60],...
'FontSize', 24, 'FontWeight','bold','Tag','mineCounter', 'Text',app.mineCounterValue);
flagCounter=uilabel(app.UIFigure, 'Position',[820,510,60,60],...
'FontSize', 24, 'FontWeight','bold', 'Tag','flagCounter', 'Text', app.flagCounterValue);
    uibutton(app.UIFigure,...
            "Position", [500,610,60,60],...
            "Text", '',...
            "Tag",'Smiley',...
            "Icon","C:\Users\Administrator\Desktop\Minesweeper\images\smiley-face.png");
   uicheckbox(app.UIFigure,"Text",'Place flag', 'Position',[920,510,300,60],'FontSize', 24, 'FontWeight','bold','Tag','flagPlacer', 'Value', 0);
       if difficulty==99
        minefield=minefield';
        app.panel1.Position = [110, 40, 30*size(minefield,2), 30*size(minefield,1)];   
       elseif difficulty==40
        app.panel1.Position=[290, 40, 30*size(minefield,1),30*size(minefield,2) ];
       else
        app.panel1.Position=[410, windowSize(2), 30*size(minefield,1),30*size(minefield,2)];
       end
    
    % Calculate button dimensions based on field size
    buttonWidth = 30;
    buttonHeight = 30;
    
    for i = 1:size(minefield,1)
        for j = 1:size(minefield,2)
            buttons{i,j} = uibutton(app.panel1, ...,
                "BackgroundColor",[0.7969,0.7969,0.7969],...
                "IconAlignment", "center",...
                "Icon", 'C:\Users\Administrator\Desktop\Minesweeper\images\square.png',...
                "UserData", [i, j]);
            
            % Set button position
            buttons{i,j}.Position = [j*buttonWidth-30 i*buttonHeight-30 buttonWidth buttonHeight];
            buttons{i,j}.BackgroundColor=[0.7969,0.7969,0.7969];
            % Set button text (initially empty)
            buttons{i,j}.Text = "";
              
            % Add callback function for button click
           
        end
    end
    for i=1:size(minefield,1)
        for j=1:size(minefield,2)
            buttons{i,j}.ButtonPushedFcn = @(src, event) buttonClicked(app, src, buttons);
        end
    end
    
       bombLocations=randi(nButtons, 1, difficulty);
       bombCounter=0;
       
    if difficulty==99
        flagCount=0;
    elseif difficulty==40
        flagCount=5;
    else
        flagCount=10;
    end
    
    for i = 1:length(bombLocations)
        bombCounter=bombCounter+1;
        flagCount=flagCount+1;
        buttonIndex = bombLocations(i);
        [row, col] = ind2sub(size(minefield), buttonIndex);
        minefield(row, col) = -1;
        buttons{row, col}.Tag='bomb';
        mineCounter.Text='';
    end
    app.mineCounterValue=num2str(bombCounter);
    app.flagCounterValue=num2str(flagCount);
    mineCounter.Text=app.mineCounterValue;
    flagCounter.Text=app.flagCounterValue;
    %C:\Users\Administrator\Desktop\Minesweeper\images - copy paste izmedju
    %racunara (Hanan's path)

    for i = 1:size(minefield,1)
        for j = 1:size(minefield,2)
            if strcmp(buttons{i, j}.Tag, '')
               if (i > 1 && i < size(minefield,1) && j > 1 && j < size(minefield,2))
                count = strcmp(buttons{i+1, j}.Tag, 'bomb') + strcmp(buttons{i+1, j-1}.Tag, 'bomb') + strcmp(buttons{i, j-1}.Tag, 'bomb') + ...
                        strcmp(buttons{i-1, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb') + ...
                        strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j+1}.Tag, 'bomb');
               elseif (i == 1 && j == 1)
                   count = strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               elseif (i == 1 && j == size(minefield,2))
                   count = strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i+1, j-1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               elseif (i == size(minefield,1) && j == size(minefield,2))
                   count = strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j}.Tag, 'bomb');
               elseif (i == size(minefield,1) && j == 1)
                   count = strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb') + strcmp(buttons{i-1, j}.Tag, 'bomb');
               elseif (i == 1)
                   count = strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j+1}.Tag, 'bomb') + ...
                           strcmp(buttons{i+1, j}.Tag, 'bomb') + strcmp(buttons{i+1, j-1}.Tag, 'bomb');
               elseif (i == size(minefield,1))
                   count = strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j-1}.Tag, 'bomb') + ...
                           strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb');
               elseif (j == 1)
                   count = strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb') + strcmp(buttons{i, j+1}.Tag, 'bomb') + ...
                            strcmp(buttons{i+1, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               else
                   count = strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j-1}.Tag, 'bomb') + strcmp(buttons{i, j-1}.Tag, 'bomb') + ...
                           strcmp(buttons{i+1, j-1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               end
               buttons{i,j}.Tag = num2str(count);
            end
        end
    end
    
end

function youAreDead(app)
    btns = findall(app.UIFigure, 'Type', 'uibutton');
        startBtn=findall(app.UIFigure, 'Tag', 'start');
    for i=1:numel(btns)
            if btns(i)~=startBtn
            btns(i).ButtonPushedFcn='';
            end
     end
    
    smileyButton=findall(app.UIFigure, 'Tag', 'Smiley');
    smileyButton.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\dead-face.png';
    
end

function buttonClicked(app, src, buttons)
    row = src.UserData(1);
    col=src.UserData(2);
    disp(app.mineCounterValue);
    disp(app.flagCounterValue);
    flagPlacer=findall(app.UIFigure, 'Tag','flagPlacer');
    checkboxClicked = any([flagPlacer.Value]);
    flagCounter = findobj(app.UIFigure, 'Tag', 'flagCounter');
 if checkboxClicked
        % Set the clicked button icon to the specified image path
        src.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\flag.png';
        flagCounter = flagCounter(1);
        currentCounter = str2double(flagCounter.Text);
        flagCounter.Text = num2str(currentCounter - 1);
        
    else

    if strcmp(src.Tag, 'bomb')
        disp('Bomb clicked!');
        src.BackgroundColor=[1,0,0];
        src.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\wrong-bomb.png';
        youAreDead(app);
        bombs = findall(app.UIFigure, 'Tag', 'bomb');
        for i=1:numel(bombs)
            if bombs(i) == src
                continue;
            end
            pause(0.3);
            bombs(i).Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\bomb.png';
        end
    else
        checkIcon(app,row,col,buttons);
        if src.Tag == '0'
           revealEmpty(app, row, col, buttons);
        end
    end
end
end
function revealEmpty(app, row, col, buttons)
    if (row==1 && col==1)
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row+1,col+1}.Tag,'open') && buttons{row+1,col+1}.Tag == '0'
            buttons{row+1,col+1}.Tag = 'open';
            revealEmpty(app,row+1,col+1,buttons);
        end
        checkIcon(app,row+1,col+1,buttons);
        
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
    elseif (row==1 && col==size(buttons,2))
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row+1,col-1}.Tag,'open') && buttons{row+1,col-1}.Tag == '0'
            buttons{row+1,col-1}.Tag = 'open';
            revealEmpty(app,row+1,col-1,buttons);
        end
        checkIcon(app,row+1,col-1,buttons);
        
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
    elseif (row==size(buttons,1) && col==size(buttons,2))
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col-1}.Tag,'open') && buttons{row-1,col-1}.Tag == '0'
            buttons{row-1,col-1}.Tag = 'open';
            revealEmpty(app,row-1,col-1,buttons);
        end
        checkIcon(app,row-1,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
    elseif (row==size(buttons,1) && col==1)
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col+1}.Tag,'open') && buttons{row-1,col+1}.Tag == '0'
            buttons{row-1,col+1}.Tag = 'open';
            revealEmpty(app,row-1,col+1,buttons);
        end
        checkIcon(app,row-1,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
    elseif (row==1)
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row+1,col-1}.Tag,'open') && buttons{row+1,col-1}.Tag == '0'
            buttons{row+1,col-1}.Tag = 'open';
            revealEmpty(app,row+1,col-1,buttons);
        end
        checkIcon(app,row+1,col-1,buttons);
        
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
        
        if ~strcmp(buttons{row+1,col+1}.Tag,'open') && buttons{row+1,col+1}.Tag == '0'
            buttons{row+1,col+1}.Tag = 'open';
            revealEmpty(app,row+1,col+1,buttons);
        end
        checkIcon(app,row+1,col+1,buttons);
    elseif (row==size(buttons,1))
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col-1}.Tag,'open') && buttons{row-1,col-1}.Tag == '0'
            buttons{row-1,col-1}.Tag = 'open';
            revealEmpty(app,row-1,col-1,buttons);
        end
        checkIcon(app,row-1,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
        
        if ~strcmp(buttons{row-1,col+1}.Tag,'open') && buttons{row-1,col+1}.Tag == '0'
            buttons{row-1,col+1}.Tag = 'open';
            revealEmpty(app,row-1,col+1,buttons);
        end
        checkIcon(app,row-1,col+1,buttons);
    elseif (col==1)
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
        
        if ~strcmp(buttons{row+1,col+1}.Tag,'open') && buttons{row+1,col+1}.Tag == '0'
            buttons{row+1,col+1}.Tag = 'open';
            revealEmpty(app,row+1,col+1,buttons);
        end
        checkIcon(app,row+1,col+1,buttons);
        
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col+1}.Tag,'open') && buttons{row-1,col+1}.Tag == '0'
            buttons{row-1,col+1}.Tag = 'open';
            revealEmpty(app,row-1,col+1,buttons);
        end
        checkIcon(app,row-1,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
    elseif (col==size(buttons,2))
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
        
        if ~strcmp(buttons{row+1,col-1}.Tag,'open') && buttons{row+1,col-1}.Tag == '0'
            buttons{row+1,col-1}.Tag = 'open';
            revealEmpty(app,row+1,col-1,buttons);
        end
        checkIcon(app,row+1,col-1,buttons);
        
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0'
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col-1}.Tag,'open') && buttons{row-1,col-1}.Tag == '0'
            buttons{row-1,col-1}.Tag = 'open';
            revealEmpty(app,row-1,col-1,buttons);
        end
        checkIcon(app,row-1,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
    else
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row+1,col-1}.Tag,'open') && buttons{row+1,col-1}.Tag == '0'
            buttons{row+1,col-1}.Tag = 'open';
            revealEmpty(app,row+1,col-1,buttons);
        end
        checkIcon(app,row+1,col-1,buttons);
        
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
        
        if ~strcmp(buttons{row+1,col+1}.Tag,'open') && buttons{row+1,col+1}.Tag == '0' 
            buttons{row+1,col+1}.Tag = 'open';
            revealEmpty(app,row+1,col+1,buttons);
        end
        checkIcon(app,row+1,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col-1}.Tag,'open') && buttons{row-1,col-1}.Tag == '0'
            buttons{row-1,col-1}.Tag = 'open';
            revealEmpty(app,row-1,col-1,buttons);
        end
        checkIcon(app,row-1,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
        
        if ~strcmp(buttons{row-1,col+1}.Tag,'open') && buttons{row-1,col+1}.Tag == '0'
            buttons{row-1,col+1}.Tag = 'open';
            revealEmpty(app,row-1,col+1,buttons);
        end
        checkIcon(app,row-1,col+1,buttons);
        
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0' 
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
    end
end

function checkIcon(~,row,col,buttons)
    switch buttons{row,col}.Tag
        case '1'
            buttons{row,col}.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\number1.png';
        case '2'
            buttons{row,col}.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\number2.png';
        case '3'
            buttons{row,col}.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\number3.png';
        case '4'
            buttons{row,col}.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\number4.png';
        case '5'
            buttons{row,col}.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\number5.png';
        case '6'
            buttons{row,col}.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\number6.png';
        case '7'
            buttons{row,col}.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\number7.png';
        case '8'
            buttons{row,col}.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\number8.png';
        otherwise
            buttons{row,col}.Icon='C:\Users\Administrator\Desktop\Minesweeper\images\number0.png';
    end
end

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.panel1.Visible='off';
            app.panel1.BorderColor=[0.50,0.50,0.50];
            app.panel1.ForegroundColor=[0.50,0.50,0.50];
            app.panel1.HighlightColor=[0.50,0.50,0.50];
            app.Minefield.BackgroundColor=[0.65,0.65,0.65];
            
        end

        % Button pushed function: StartButton
        function StartGame(app, event)
            clc
            difficulty=app.ChoosedifficultyDropDown.Value;
            switch difficulty
                case 'Medium'
                    field=zeros(16);
                    generateBomb(app,field,40);
                case 'Hard'
                    field=zeros(30,16);
                    generateBomb(app,field,99);
                otherwise
                    field=zeros(8);
                    generateBomb(app,field,10);
            
            end
            
            %app.UIFigure.Visible='off';
            app.mineCounterValue='';
            app.flagCounterValue='';

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'bomb.png');
            app.ChoosedifficultyDropDownLabel = uilabel(app.UIFigure);
            app.ChoosedifficultyDropDownLabel.Text = 'Choose difficulty';
            app.ChoosedifficultyDropDown = uidropdown(app.UIFigure);
            app.ChoosedifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.ChoosedifficultyDropDown.Placeholder = '...';
            app.ChoosedifficultyDropDown.Value = 'Easy';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartGame, true);
            app.StartButton.Tag = 'start';
            app.StartButton.Icon = fullfile(pathToMLAPP, 'images', 'bomb.png');
            app.StartButton.Text = 'Start';
            app.panel1 = uipanel(app.UIFigure);
            app.Minefield = uigridlayout(app.panel1);
            app.WelcometominesweeperLabel = uilabel(app.UIFigure);
            app.WelcometominesweeperLabel.Interpreter = 'html';
            app.WelcometominesweeperLabel.Text = 'Welcome to minesweeper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Minesweeper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end